classdef Panda_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        KalibrierenTab                  matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        HilfeButton                     matlab.ui.control.Button
        KalibrierungPanel               matlab.ui.container.Panel
        SicherheitsabstandSpinner       matlab.ui.control.Spinner
        SicherheitsabstandSpinnerLabel  matlab.ui.control.Label
        SicherheitsabstandzumMesspunktmmLabel  matlab.ui.control.Label
        AbstandRoboterMesspunktSpinner  matlab.ui.control.Spinner
        AbstandRoboterMesspunktSpinnerLabel  matlab.ui.control.Label
        AbstandLautsprecherzurMitteMesseinrichtungmmLabel  matlab.ui.control.Label
        WinkelHandvertikalSpinner       matlab.ui.control.Spinner
        WinkelHandvertikalSpinnerLabel  matlab.ui.control.Label
        KalibrierenZSpinner             matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        KalibrierenYSpinner             matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        KalibrierenXSpinner             matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        AusrichtungStartpositionRoboter0GradLabel  matlab.ui.control.Label
        AbstandTCPSpinner               matlab.ui.control.Spinner
        AbstandTCPSpinnerLabel          matlab.ui.control.Label
        AbstandTCPvomRoboterflanschmmLabel  matlab.ui.control.Label
        KalibrierungspeichernundladenPanel  matlab.ui.container.Panel
        KalibrierdatenspeichernButton   matlab.ui.control.Button
        KalibrierdatenladenButton       matlab.ui.control.Button
        RoboterSteuerungPanel           matlab.ui.container.Panel
        RoboterbewegenButton            matlab.ui.control.Button
        RoboterSimulationPanel          matlab.ui.container.Panel
        SimulierenButton                matlab.ui.control.Button
        RoboterSimulationanzeigenButton  matlab.ui.control.Button
        VektorModusTab                  matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        HilfeButton_2                   matlab.ui.control.Button
        SteuerungsmodusPanel_2          matlab.ui.container.Panel
        IntervalldauersSpinner          matlab.ui.control.Spinner
        IntervalldauersSpinnerLabel     matlab.ui.control.Label
        SteuerungsmodusKnob             matlab.ui.control.DiscreteKnob
        SteuerungsmodusKnobLabel        matlab.ui.control.Label
        WegpunkteVektorenfestlegenPanel  matlab.ui.container.Panel
        WegpunkteeingebenTextArea       matlab.ui.control.TextArea
        WegpunkteeingebenTextAreaLabel  matlab.ui.control.Label
        FormatbeiEingabexyzxyzxyzLabel  matlab.ui.control.Label
        RoboterSteuerungPanel_2         matlab.ui.container.Panel
        NchstenPunktanfahrenButtonRoboter  matlab.ui.control.Button
        RoboterstartenButton            matlab.ui.control.Button
        RoboterSimulationPanel_2        matlab.ui.container.Panel
        NchstenPunktanfahrenButton      matlab.ui.control.Button
        SimulationstartenButton_2       matlab.ui.control.Button
        RoboterSimulationanzeigenButton_4  matlab.ui.control.Button
        LadenundSpeichernPanel          matlab.ui.container.Panel
        WegpunktealsDateispeichernButton  matlab.ui.control.Button
        WegpunkteausDateiladenButton    matlab.ui.control.Button
        WinkelModusTab                  matlab.ui.container.Tab
        Image_4                         matlab.ui.control.Image
        HilfeButton_3                   matlab.ui.control.Button
        SteuerungsmodusPanel            matlab.ui.container.Panel
        IntervalldauersSpinner_2        matlab.ui.control.Spinner
        IntervalldauersSpinner_2Label   matlab.ui.control.Label
        SteuerungsmodusKnob_2           matlab.ui.control.DiscreteKnob
        SteuerungsmodusKnob_2Label      matlab.ui.control.Label
        WegpunkteWinkelfestlegenPanel   matlab.ui.container.Panel
        WinkeleingebenTextArea          matlab.ui.control.TextArea
        WinkeleingebenTextAreaLabel     matlab.ui.control.Label
        FormatbeiEingabeLabel           matlab.ui.control.Label
        RoboterSteuerungPanel_3         matlab.ui.container.Panel
        NchstenPunktanfahrenButtonRoboter_2  matlab.ui.control.Button
        RoboterstartenButton_2          matlab.ui.control.Button
        RoboterSimulationPanel_3        matlab.ui.container.Panel
        NchstenPunktanfahrenButton_2    matlab.ui.control.Button
        SimulationstartenButton_3       matlab.ui.control.Button
        RoboterSimulationanzeigenButton_5  matlab.ui.control.Button
        LadenundSpeichernPanel_2        matlab.ui.container.Panel
        WinkelalsDateispeichernButton   matlab.ui.control.Button
        WinkelausDateiladenButton       matlab.ui.control.Button
        BeispieleTab                    matlab.ui.container.Tab
        RoboterModelladenButton_2       matlab.ui.control.Button
        SimulationstartenButton         matlab.ui.control.Button
        RoboterModelladenButton         matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        RunScriptButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        robot = loadrobot("frankaEmikaPanda",DataFormat="column");

    end

    methods (Access = public)

        function results = move_robot(robot, fixed_point, radius)
            % Calculate the desired position of the TCP.
            tcp_position = fixed_point + radius * [cos(angle), sin(angle), 0];

            % Calculate the joint angles for the desired position.
            q = inverse_kinematics(robot, tcp_position);

            % Move the robot to the desired position.
            moveto(robot, q);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scriptPathSetupWorkspace = fullfile("setup_workspace.m");
            run(scriptPathSetupWorkspace);
        end

        % Button pushed function: RoboterModelladenButton
        function RoboterModelladenButtonPushed(app, event)
            %robot = loadrobot("frankaEmikaPanda",DataFormat="column");
            set(gcf, "Visible","on")
            show(app.robot);
        end

        % Button pushed function: SimulationstartenButton
        function SimulationstartenButtonPushed(app, event)
            %% Defining Time vector for the trajectories
            tpts = 0:4;
            sampleRate = 20;
            tvec = tpts(1):1/sampleRate:tpts(end);
            numSamples = length(tvec);

            %% Task-Space Trajectories mit Inverse Kinematics
            frankaSpaceWaypoints = [0.5 0.25 0.25; 0.5 0 0.35; 0.5 -0.25 0.25; 0.5 0.25 0.25]';
            frankaTimepoints = linspace(tvec(1),tvec(end),4);
            [pos,vel] = minjerkpolytraj(frankaSpaceWaypoints,frankaTimepoints,numSamples);

            rng(0) % Seed the RNG so the inverse kinematics solution is consistent
            ik = inverseKinematics(RigidBodyTree=app.robot);
            ik.SolverParameters.AllowRandomRestart = false;
            q = zeros(9,numSamples);
            weights = [0.2 0.2 0.2 1 1 1]; % Prioritize position over orientation
            initialGuess = [0, 0, 0, -pi/2, 0, 0, 0, 0.01, 0.01]'; % Choose an initial guess within the robot joint limits
            for i = 1:size(pos,2)
                targetPose = trvec2tform(pos(:,i)')*eul2tform([0, 0, pi]);
                q(:,i) = ik("panda_hand",targetPose,weights,initialGuess);
                initialGuess = q(:,i); % Use the last result as the next initial guess
            end

            rc = rateControl(sampleRate);
            for i = 1:numSamples
                show(app.robot, q(:,i),FastUpdate=true,PreservePlot=false);
                waitfor(rc);
            end
        end

        % Callback function
        function TrajectoryButtonPushed(app, event)
            function move_robot(robot, fixed_point, radius, num_samples)

                % Define the start and end points of the trajectory.
                tcp_start_position = fixed_point - radius * [1, 0, 0];
                tcp_end_position = fixed_point + radius * [1, 0, 0];

                % Calculate the trajectory.
                tcp_trajectory = semicircle_trajectory(tcp_start_position, tcp_end_position, num_samples);

                % Move the robot along the trajectory.
                movealongtrajectory(robot, tcp_trajectory);
            end

            % Define the fixed point.
            fixed_point = [0, 0, 0];

            % Define the radius of the hemisphere.
            radius = 1;

            % Define the number of samples for the trajectory.
            num_samples = 100;

            % Move the robot along the trajectory.
            move_robot(app.robot, fixed_point, radius, num_samples);
        end

        % Button pushed function: RunScriptButton
        function RunScriptButtonPushed(app, event)
            % Get the value of the EditField
            valueEditField = app.EditField.Value

            scriptPath = fullfile("TestRunScriptFromApp.m");
            run(scriptPath);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.AbstandTCPvomRoboterflanschmmLabel.Text = "value";
        end

        % Button pushed function: KalibrierdatenladenButton
        function KalibrierdatenladenButtonPushed(app, event)
            %Initialisiert und startet Script "CalibrationData" um an die
            %Kalibrierdaten zu kommen.
            scriptPathCalibrationData = fullfile("CalibrationData.m");
            run(scriptPathCalibrationData);

            % Globale Variablen für Setup in Zahlenfelder einfügen:
            set(app.KalibrierenXSpinner, "Value", double(kalibrieren_x));
            set(app.KalibrierenYSpinner, "Value", double(kalibrieren_y));
            set(app.KalibrierenZSpinner, "Value", double(kalibrieren_z));

            set(app.AbstandTCPSpinner, "Value", double(kalibrieren_abstand_TCP));
            set(app.AbstandRoboterMesspunktSpinner, "Value", double(kalibrieren_abstand_messpunkt));
            set(app.SicherheitsabstandSpinner, "Value", double(kalibrieren_abstand_sicherheit));
            set(app.WinkelHandvertikalSpinner, "Value", double(kalibrieren_winkel_TCP));

        end

        % Button pushed function: KalibrierdatenspeichernButton
        function KalibrierdatenspeichernButtonPushed(app, event)
            %Initialisiert und startet Script "CalibrationData" um an die
            %Kalibrierdaten zu kommen.  ||  ACHTUNG!!! Gesamte Funktion ist
            %exakt auch in CalibrationData.m im Skript. Wenn etwas geändert
            %werden soll, dann also immer bei beiden Dateien.
            scriptPathCalibrationData = fullfile("CalibrationData.m");
            run(scriptPathCalibrationData);

            % Werte der Eingabefelder lesen
            x = get(app.KalibrierenXSpinner, 'Value');
            y = get(app.KalibrierenYSpinner, 'Value');
            z = get(app.KalibrierenZSpinner, 'Value');

            TCP = get(app.AbstandTCPSpinner, 'Value');
            messpunkt = get(app.AbstandRoboterMesspunktSpinner, 'Value');
            sicherheit = get(app.SicherheitsabstandSpinner, 'Value');
            winkel = get(app.WinkelHandvertikalSpinner, 'Value');

            % Werte der Eingabefelder in die globalen Variablen schreiben
            kalibrieren_x = x;
            kalibrieren_y = y;
            kalibrieren_z = z;

            kalibrieren_abstand_TCP = TCP;
            kalibrieren_abstand_messpunkt = messpunkt;
            kalibrieren_abstand_sicherheit = sicherheit;
            kalibrieren_winkel_TCP = winkel;


            % Globale Variablen in das Skript speichern
            save(fullfile('CalibrationData.mat'), 'kalibrieren_x', 'kalibrieren_y', 'kalibrieren_z', 'kalibrieren_abstand_TCP', 'kalibrieren_abstand_messpunkt', 'kalibrieren_abstand_sicherheit','kalibrieren_winkel_TCP');
        end

        % Button pushed function: RoboterModelladenButton_2
        function RoboterModelladenButton_2Pushed(app, event)
            set(gcf, "Visible","on")
            show(app.robot);
        end

        % Button pushed function: RoboterSimulationanzeigenButton
        function RoboterSimulationanzeigenButtonPushed(app, event)
            % Make the figure visible
            set(gcf, "Visible", "on");


            % Show the reference point
            show(app.robot);
            hold on;
            fixedPoint = [1, 0, 0.5];
            % Plot the fixed point as a red ball
            plot3(fixedPoint(1), fixedPoint(2), fixedPoint(3), 'ro', 'MarkerSize', 5, 'LineWidth', 5);
            hold off;
        end

        % Button pushed function: WegpunktealsDateispeichernButton
        function WegpunktealsDateispeichernButtonPushed(app, event)
            % Get the content of the "Wegpunkte" TextArea
            waypoints = app.WegpunkteeingebenTextArea.Value;

            % Open an explorer window and allow the user to name the file
            [filename, pathname] = uiputfile('*.mat', 'Save Waypoints');

            % If the user selected a file, save the waypoints to the file
            if filename ~= 0
                save(fullfile(pathname, filename), 'waypoints');
            end

        end

        % Button pushed function: WegpunkteausDateiladenButton
        function WegpunkteausDateiladenButtonPushed(app, event)
            % Open an explorer window and allow the user to select a file
            [filename, pathname] = uigetfile('*.mat', 'Lade Wegpunkte');

            % If the user selected a file, load the waypoints from the file
            if filename ~= 0
                load(fullfile(pathname, filename));
                app.WegpunkteeingebenTextArea.Value = waypoints;
            end
        end

        % Value changed function: SteuerungsmodusKnob
        function SteuerungsmodusKnobValueChanged(app, event)
            state = app.SteuerungsmodusKnob.Value;
            % If the state is "Zeitgesteuert", enable the interval duration spinner
            if state == "Zeitgesteuert"
                app.IntervalldauersSpinner.Enable = true;
            else
                app.IntervalldauersSpinner.Enable = false;
            end

            % If the state is "Benutzergersteuert", enable the interval
            % duration spinner
            if state == "Benutzergesteuert"
                app.NchstenPunktanfahrenButton.Enable = true;
            else
                app.NchstenPunktanfahrenButton.Enable = false;
            end

            % If the state is "Benutzergersteuert", enable the interval
            % duration spinner
            if state == "Benutzergesteuert"
                app.NchstenPunktanfahrenButtonRoboter.Enable = true;
            else
                app.NchstenPunktanfahrenButtonRoboter.Enable = false;
            end

        end

        % Button pushed function: SimulierenButton
        function SimulierenButtonPushed(app, event)

        end

        % Button pushed function: NchstenPunktanfahrenButton
        function NchstenPunktanfahrenButtonPushed(app, event)

        end

        % Button pushed function: NchstenPunktanfahrenButtonRoboter
        function NchstenPunktanfahrenButtonRoboterPushed(app, event)

        end

        % Button pushed function: HilfeButton
        function HilfeButtonPushed(app, event)
            web("https://github.com/");
        end

        % Button pushed function: HilfeButton_2
        function HilfeButton_2Pushed(app, event)
            web("https://github.com/");
        end

        % Button pushed function: HilfeButton_3
        function HilfeButton_3Pushed(app, event)
            web("https://github.com/");
        end

        % Value changed function: SteuerungsmodusKnob_2
        function SteuerungsmodusKnob_2ValueChanged(app, event)
            state = app.SteuerungsmodusKnob_2.Value;
            % If the state is "Zeitgesteuert", enable the interval duration spinner
            if state == "Zeitgesteuert"
                app.IntervalldauersSpinner_2.Enable = true;
            else
                app.IntervalldauersSpinner_2.Enable = false;
            end

            % If the state is "Benutzergersteuert", enable the interval
            % duration spinner
            if state == "Benutzergesteuert"
                app.NchstenPunktanfahrenButton_2.Enable = true;
            else
                app.NchstenPunktanfahrenButton_2.Enable = false;
            end

            % If the state is "Benutzergersteuert", enable the interval
            % duration spinner
            if state == "Benutzergesteuert"
                app.NchstenPunktanfahrenButtonRoboter_2.Enable = true;
            else
                app.NchstenPunktanfahrenButtonRoboter_2.Enable = false;
            end

        end

        % Button pushed function: WinkelausDateiladenButton
        function WinkelausDateiladenButtonPushed(app, event)
            % Open an explorer window and allow the user to select a file
            [filename, pathname] = uigetfile('*.mat', 'Lade Wegpunkte');

            % If the user selected a file, load the waypoints from the file
            if filename ~= 0
                load(fullfile(pathname, filename));
                app.WinkeleingebenTextArea.Value = waypoints;
            end
        end

        % Button pushed function: WinkelalsDateispeichernButton
        function WinkelalsDateispeichernButtonPushed(app, event)
            % Get the content of the "Wegpunkte" TextArea
            waypoints = app.WinkeleingebenTextArea.Value;

            % Open an explorer window and allow the user to name the file
            [filename, pathname] = uiputfile('*.mat', 'Wegpunkte speichern');

            % If the user selected a file, save the waypoints to the file
            if filename ~= 0
                save(fullfile(pathname, filename), 'waypoints');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KalibrierenTab = uitab(app.TabGroup);
            app.KalibrierenTab.Title = 'Kalibrieren';
            app.RoboterSimulationPanel = uipanel(app.KalibrierenTab);
            app.RoboterSimulationPanel.Title = 'Roboter Simulation';
            app.RoboterSimulationanzeigenButton = uibutton(app.RoboterSimulationPanel, 'push');
            app.RoboterSimulationanzeigenButton.ButtonPushedFcn = createCallbackFcn(app, @RoboterSimulationanzeigenButtonPushed, true);
            app.RoboterSimulationanzeigenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'easel.svg');
            app.RoboterSimulationanzeigenButton.Tooltip = {'Zeigt die Grafik zur Robotersimulation an'};
            app.RoboterSimulationanzeigenButton.Text = 'Roboter Simulation anzeigen';
            app.SimulierenButton = uibutton(app.RoboterSimulationPanel, 'push');
            app.SimulierenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulierenButtonPushed, true);
            app.SimulierenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'pc-display-horizontal.svg');
            app.SimulierenButton.Tooltip = {'Fährt Simulation auf festgelegten Startpunkt'};
            app.SimulierenButton.Text = 'Simulieren';
            app.RoboterSteuerungPanel = uipanel(app.KalibrierenTab);
            app.RoboterSteuerungPanel.Title = 'Roboter Steuerung';
            app.RoboterbewegenButton = uibutton(app.RoboterSteuerungPanel, 'push');
            app.RoboterbewegenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'robot.svg');
            app.RoboterbewegenButton.Tooltip = {'Bewegt den Roboterarm'};
            app.RoboterbewegenButton.Text = 'Roboter bewegen';
            app.KalibrierungspeichernundladenPanel = uipanel(app.KalibrierenTab);
            app.KalibrierungspeichernundladenPanel.Title = 'Kalibrierung speichern und laden';
            app.KalibrierdatenladenButton = uibutton(app.KalibrierungspeichernundladenPanel, 'push');
            app.KalibrierdatenladenButton.ButtonPushedFcn = createCallbackFcn(app, @KalibrierdatenladenButtonPushed, true);
            app.KalibrierdatenladenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'file-earmark-arrow-up.svg');
            app.KalibrierdatenladenButton.Tooltip = {'Kalibrierdaten aus Matlab Workspace laden'};
            app.KalibrierdatenladenButton.Text = 'Kalibrierdaten laden';
            app.KalibrierdatenspeichernButton = uibutton(app.KalibrierungspeichernundladenPanel, 'push');
            app.KalibrierdatenspeichernButton.ButtonPushedFcn = createCallbackFcn(app, @KalibrierdatenspeichernButtonPushed, true);
            app.KalibrierdatenspeichernButton.Icon = fullfile(pathToMLAPP, 'Icons', 'floppy.svg');
            app.KalibrierdatenspeichernButton.Tooltip = {'Kalibrierdaten in Matlab Workspace speichern'};
            app.KalibrierdatenspeichernButton.Text = 'Kalibrierdaten speichern';
            app.KalibrierungPanel = uipanel(app.KalibrierenTab);
            app.KalibrierungPanel.Title = 'Kalibrierung';
            app.AbstandTCPvomRoboterflanschmmLabel = uilabel(app.KalibrierungPanel);
            app.AbstandTCPvomRoboterflanschmmLabel.Text = 'Abstand TCP vom Roboterflansch [mm]';
            app.AbstandTCPSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.AbstandTCPSpinnerLabel.Text = 'Abstand TCP';
            app.AbstandTCPSpinner = uispinner(app.KalibrierungPanel);
            app.AbstandTCPSpinner.Tooltip = {'Der Abstand vom Lautsprecher zum Roboterflansch'};
            app.AusrichtungStartpositionRoboter0GradLabel = uilabel(app.KalibrierungPanel);
            app.AusrichtungStartpositionRoboter0GradLabel.Text = 'Ausrichtung Startposition Roboter (0 Grad)';
            app.XSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.XSpinnerLabel.Text = 'X';
            app.KalibrierenXSpinner = uispinner(app.KalibrierungPanel);
            app.YSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.KalibrierenYSpinner = uispinner(app.KalibrierungPanel);
            app.ZSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.KalibrierenZSpinner = uispinner(app.KalibrierungPanel);
            app.WinkelHandvertikalSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.WinkelHandvertikalSpinnerLabel.Text = 'Winkel Hand (vertikal)';
            app.WinkelHandvertikalSpinner = uispinner(app.KalibrierungPanel);
            app.AbstandLautsprecherzurMitteMesseinrichtungmmLabel = uilabel(app.KalibrierungPanel);
            app.AbstandLautsprecherzurMitteMesseinrichtungmmLabel.Text = 'Abstand Lautsprecher zur Mitte Messeinrichtung [mm]';
            app.AbstandRoboterMesspunktSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.AbstandRoboterMesspunktSpinnerLabel.Text = 'Abstand Roboter Messpunkt';
            app.AbstandRoboterMesspunktSpinner = uispinner(app.KalibrierungPanel);
            app.SicherheitsabstandzumMesspunktmmLabel = uilabel(app.KalibrierungPanel);
            app.SicherheitsabstandzumMesspunktmmLabel.Text = 'Sicherheitsabstand zum Messpunkt [mm]';
            app.SicherheitsabstandSpinnerLabel = uilabel(app.KalibrierungPanel);
            app.SicherheitsabstandSpinnerLabel.Text = 'Sicherheitsabstand';
            app.SicherheitsabstandSpinner = uispinner(app.KalibrierungPanel);
            app.SicherheitsabstandSpinner.Tooltip = {'Minimaler Abstand, der vom Roboter zum Messpunkt eingehalten werden soll'};
            app.HilfeButton = uibutton(app.KalibrierenTab, 'push');
            app.HilfeButton.ButtonPushedFcn = createCallbackFcn(app, @HilfeButtonPushed, true);
            app.HilfeButton.Icon = fullfile(pathToMLAPP, 'Icons', 'question-circle.svg');
            app.HilfeButton.Text = 'Hilfe';
            app.Image_2 = uiimage(app.KalibrierenTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Project_Logo.svg');
            app.Image2 = uiimage(app.KalibrierenTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Panda.jpg');
            app.VektorModusTab = uitab(app.TabGroup);
            app.VektorModusTab.Title = 'Vektor-Modus';
            app.LadenundSpeichernPanel = uipanel(app.VektorModusTab);
            app.LadenundSpeichernPanel.Title = 'Laden und Speichern';
            app.WegpunkteausDateiladenButton = uibutton(app.LadenundSpeichernPanel, 'push');
            app.WegpunkteausDateiladenButton.ButtonPushedFcn = createCallbackFcn(app, @WegpunkteausDateiladenButtonPushed, true);
            app.WegpunkteausDateiladenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'file-earmark-arrow-up.svg');
            app.WegpunkteausDateiladenButton.Text = 'Wegpunkte aus Datei laden';
            app.WegpunktealsDateispeichernButton = uibutton(app.LadenundSpeichernPanel, 'push');
            app.WegpunktealsDateispeichernButton.ButtonPushedFcn = createCallbackFcn(app, @WegpunktealsDateispeichernButtonPushed, true);
            app.WegpunktealsDateispeichernButton.Icon = fullfile(pathToMLAPP, 'Icons', 'floppy.svg');
            app.WegpunktealsDateispeichernButton.Text = 'Wegpunkte als Datei speichern';
            app.RoboterSimulationPanel_2 = uipanel(app.VektorModusTab);
            app.RoboterSimulationPanel_2.Title = 'Roboter Simulation';
            app.RoboterSimulationanzeigenButton_4 = uibutton(app.RoboterSimulationPanel_2, 'push');
            app.RoboterSimulationanzeigenButton_4.Icon = fullfile(pathToMLAPP, 'Icons', 'easel.svg');
            app.RoboterSimulationanzeigenButton_4.Text = 'Roboter Simulation anzeigen';
            app.SimulationstartenButton_2 = uibutton(app.RoboterSimulationPanel_2, 'push');
            app.SimulationstartenButton_2.Icon = fullfile(pathToMLAPP, 'Icons', 'pc-display-horizontal.svg');
            app.SimulationstartenButton_2.Text = 'Simulation starten';
            app.NchstenPunktanfahrenButton = uibutton(app.RoboterSimulationPanel_2, 'push');
            app.NchstenPunktanfahrenButton.ButtonPushedFcn = createCallbackFcn(app, @NchstenPunktanfahrenButtonPushed, true);
            app.NchstenPunktanfahrenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'play-circle.svg');
            app.NchstenPunktanfahrenButton.Enable = 'off';
            app.NchstenPunktanfahrenButton.Text = 'Nächsten Punkt anfahren';
            app.RoboterSteuerungPanel_2 = uipanel(app.VektorModusTab);
            app.RoboterSteuerungPanel_2.Title = ' Roboter Steuerung';
            app.RoboterstartenButton = uibutton(app.RoboterSteuerungPanel_2, 'push');
            app.RoboterstartenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'robot.svg');
            app.RoboterstartenButton.Tooltip = {'Programm mit Roboterarm starten'};
            app.RoboterstartenButton.Text = 'Roboter starten';
            app.NchstenPunktanfahrenButtonRoboter = uibutton(app.RoboterSteuerungPanel_2, 'push');
            app.NchstenPunktanfahrenButtonRoboter.ButtonPushedFcn = createCallbackFcn(app, @NchstenPunktanfahrenButtonRoboterPushed, true);
            app.NchstenPunktanfahrenButtonRoboter.Icon = fullfile(pathToMLAPP, 'Icons', 'play-circle.svg');
            app.NchstenPunktanfahrenButtonRoboter.Enable = 'off';
            app.NchstenPunktanfahrenButtonRoboter.Text = 'Nächsten Punkt anfahren';
            app.WegpunkteVektorenfestlegenPanel = uipanel(app.VektorModusTab);
            app.WegpunkteVektorenfestlegenPanel.Title = 'Wegpunkte Vektoren festlegen';
            app.FormatbeiEingabexyzxyzxyzLabel = uilabel(app.WegpunkteVektorenfestlegenPanel);
            app.FormatbeiEingabexyzxyzxyzLabel.Text = {'Format bei Eingabe: '; ''; 'x, y, z'; 'x, y, z'; 'x, y, z'};
            app.WegpunkteeingebenTextAreaLabel = uilabel(app.WegpunkteVektorenfestlegenPanel);
            app.WegpunkteeingebenTextAreaLabel.Text = {'Wegpunkte'; 'eingeben'};
            app.WegpunkteeingebenTextArea = uitextarea(app.WegpunkteVektorenfestlegenPanel);
            app.WegpunkteeingebenTextArea.Tooltip = {'Eingabe der Wegpunkte als Vektoren [x, y, z]'};
            app.SteuerungsmodusPanel_2 = uipanel(app.VektorModusTab);
            app.SteuerungsmodusPanel_2.Title = 'Steuerungsmodus';
            app.SteuerungsmodusKnobLabel = uilabel(app.SteuerungsmodusPanel_2);
            app.SteuerungsmodusKnobLabel.Text = 'Steuerungsmodus';
            app.SteuerungsmodusKnob = uiknob(app.SteuerungsmodusPanel_2, 'discrete');
            app.SteuerungsmodusKnob.Items = {'Kontinuierlich', 'Zeitgesteuert', 'Benutzergesteuert'};
            app.SteuerungsmodusKnob.ValueChangedFcn = createCallbackFcn(app, @SteuerungsmodusKnobValueChanged, true);
            app.SteuerungsmodusKnob.Value = 'Kontinuierlich';
            app.IntervalldauersSpinnerLabel = uilabel(app.SteuerungsmodusPanel_2);
            app.IntervalldauersSpinnerLabel.Enable = 'off';
            app.IntervalldauersSpinnerLabel.Text = 'Intervalldauer [s]';
            app.IntervalldauersSpinner = uispinner(app.SteuerungsmodusPanel_2);
            app.IntervalldauersSpinner.Step = 0.1;
            app.IntervalldauersSpinner.Limits = [0 Inf];
            app.IntervalldauersSpinner.ValueDisplayFormat = '%.2f';
            app.IntervalldauersSpinner.Enable = 'off';
            app.IntervalldauersSpinner.Tooltip = {'Intervalldauer zwischen den einzelnen Schritten in [s]'};
            app.HilfeButton_2 = uibutton(app.VektorModusTab, 'push');
            app.HilfeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HilfeButton_2Pushed, true);
            app.HilfeButton_2.Icon = fullfile(pathToMLAPP, 'Icons', 'question-circle.svg');
            app.HilfeButton_2.Text = 'Hilfe';
            app.Image_3 = uiimage(app.VektorModusTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Project_Logo.svg');
            app.WinkelModusTab = uitab(app.TabGroup);
            app.WinkelModusTab.Title = 'Winkel-Modus';
            app.LadenundSpeichernPanel_2 = uipanel(app.WinkelModusTab);
            app.LadenundSpeichernPanel_2.Title = 'Laden und Speichern';
            app.WinkelausDateiladenButton = uibutton(app.LadenundSpeichernPanel_2, 'push');
            app.WinkelausDateiladenButton.ButtonPushedFcn = createCallbackFcn(app, @WinkelausDateiladenButtonPushed, true);
            app.WinkelausDateiladenButton.Icon = fullfile(pathToMLAPP, 'Icons', 'file-earmark-arrow-up.svg');
            app.WinkelausDateiladenButton.Text = 'Winkel aus Datei laden';
            app.WinkelalsDateispeichernButton = uibutton(app.LadenundSpeichernPanel_2, 'push');
            app.WinkelalsDateispeichernButton.ButtonPushedFcn = createCallbackFcn(app, @WinkelalsDateispeichernButtonPushed, true);
            app.WinkelalsDateispeichernButton.Icon = fullfile(pathToMLAPP, 'Icons', 'floppy.svg');
            app.WinkelalsDateispeichernButton.Text = 'Winkel als Datei speichern';
            app.RoboterSimulationPanel_3 = uipanel(app.WinkelModusTab);
            app.RoboterSimulationPanel_3.Title = 'Roboter Simulation';
            app.RoboterSimulationanzeigenButton_5 = uibutton(app.RoboterSimulationPanel_3, 'push');
            app.RoboterSimulationanzeigenButton_5.Icon = fullfile(pathToMLAPP, 'Icons', 'easel.svg');
            app.RoboterSimulationanzeigenButton_5.Text = 'Roboter Simulation anzeigen';
            app.SimulationstartenButton_3 = uibutton(app.RoboterSimulationPanel_3, 'push');
            app.SimulationstartenButton_3.Icon = fullfile(pathToMLAPP, 'Icons', 'pc-display-horizontal.svg');
            app.SimulationstartenButton_3.Text = 'Simulation starten';
            app.NchstenPunktanfahrenButton_2 = uibutton(app.RoboterSimulationPanel_3, 'push');
            app.NchstenPunktanfahrenButton_2.Icon = fullfile(pathToMLAPP, 'Icons', 'play-circle.svg');
            app.NchstenPunktanfahrenButton_2.Enable = 'off';
            app.NchstenPunktanfahrenButton_2.Text = 'Nächsten Punkt anfahren';
            app.RoboterSteuerungPanel_3 = uipanel(app.WinkelModusTab);
            app.RoboterSteuerungPanel_3.Title = ' Roboter Steuerung';
            app.RoboterstartenButton_2 = uibutton(app.RoboterSteuerungPanel_3, 'push');
            app.RoboterstartenButton_2.Icon = fullfile(pathToMLAPP, 'Icons', 'robot.svg');
            app.RoboterstartenButton_2.Tooltip = {'Programm mit Roboterarm starten'};
            app.RoboterstartenButton_2.Text = 'Roboter starten';
            app.NchstenPunktanfahrenButtonRoboter_2 = uibutton(app.RoboterSteuerungPanel_3, 'push');
            app.NchstenPunktanfahrenButtonRoboter_2.Icon = fullfile(pathToMLAPP, 'Icons', 'play-circle.svg');
            app.NchstenPunktanfahrenButtonRoboter_2.Enable = 'off';
            app.NchstenPunktanfahrenButtonRoboter_2.Text = 'Nächsten Punkt anfahren';
            app.WegpunkteWinkelfestlegenPanel = uipanel(app.WinkelModusTab);
            app.WegpunkteWinkelfestlegenPanel.Title = 'Wegpunkte Winkel festlegen';
            app.FormatbeiEingabeLabel = uilabel(app.WegpunkteWinkelfestlegenPanel);
            app.FormatbeiEingabeLabel.Text = {'Format bei Eingabe: '; ''; 'α, ⁠β⁠, Δ'; 'α, ⁠β⁠, Δ'; 'α, ⁠β⁠, Δ'; ''; 'mit'; 'α = horizontaler Winkel'; 'β = vertikaler Winkel'; 'Δ = Distanz zum Messpunkt'};
            app.WinkeleingebenTextAreaLabel = uilabel(app.WegpunkteWinkelfestlegenPanel);
            app.WinkeleingebenTextAreaLabel.Text = {'Winkel'; 'eingeben'};
            app.WinkeleingebenTextArea = uitextarea(app.WegpunkteWinkelfestlegenPanel);
            app.WinkeleingebenTextArea.Tooltip = {'Eingabe der Wegpunkte als Vektoren [x, y, z]'};
            app.SteuerungsmodusPanel = uipanel(app.WinkelModusTab);
            app.SteuerungsmodusPanel.Title = 'Steuerungsmodus';
            app.SteuerungsmodusKnob_2Label = uilabel(app.SteuerungsmodusPanel);
            app.SteuerungsmodusKnob_2Label.Text = 'Steuerungsmodus';
            app.SteuerungsmodusKnob_2 = uiknob(app.SteuerungsmodusPanel, 'discrete');
            app.SteuerungsmodusKnob_2.Items = {'Kontinuierlich', 'Zeitgesteuert', 'Benutzergesteuert'};
            app.SteuerungsmodusKnob_2.ValueChangedFcn = createCallbackFcn(app, @SteuerungsmodusKnob_2ValueChanged, true);
            app.SteuerungsmodusKnob_2.Value = 'Kontinuierlich';
            app.IntervalldauersSpinner_2Label = uilabel(app.SteuerungsmodusPanel);
            app.IntervalldauersSpinner_2Label.Enable = 'off';
            app.IntervalldauersSpinner_2Label.Text = 'Intervalldauer [s]';
            app.IntervalldauersSpinner_2 = uispinner(app.SteuerungsmodusPanel);
            app.IntervalldauersSpinner_2.Step = 0.1;
            app.IntervalldauersSpinner_2.Limits = [0 Inf];
            app.IntervalldauersSpinner_2.ValueDisplayFormat = '%.2f';
            app.IntervalldauersSpinner_2.Enable = 'off';
            app.IntervalldauersSpinner_2.Tooltip = {'Intervalldauer zwischen den einzelnen Schritten in [s]'};
            app.HilfeButton_3 = uibutton(app.WinkelModusTab, 'push');
            app.HilfeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HilfeButton_3Pushed, true);
            app.HilfeButton_3.Icon = fullfile(pathToMLAPP, 'Icons', 'question-circle.svg');
            app.HilfeButton_3.Text = 'Hilfe';
            app.Image_4 = uiimage(app.WinkelModusTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Project_Logo.svg');
            app.BeispieleTab = uitab(app.TabGroup);
            app.BeispieleTab.Title = 'Beispiele';
            app.RunScriptButton = uibutton(app.BeispieleTab, 'push');
            app.RunScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptButtonPushed, true);
            app.RunScriptButton.Text = 'RunScript';
            app.EditFieldLabel = uilabel(app.BeispieleTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.BeispieleTab, 'numeric');
            app.RoboterModelladenButton = uibutton(app.BeispieleTab, 'push');
            app.RoboterModelladenButton.ButtonPushedFcn = createCallbackFcn(app, @RoboterModelladenButtonPushed, true);
            app.RoboterModelladenButton.Text = 'Roboter Model laden';
            app.SimulationstartenButton = uibutton(app.BeispieleTab, 'push');
            app.SimulationstartenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationstartenButtonPushed, true);
            app.SimulationstartenButton.Text = 'Simulation starten';
            app.RoboterModelladenButton_2 = uibutton(app.BeispieleTab, 'push');
            app.RoboterModelladenButton_2.ButtonPushedFcn = createCallbackFcn(app, @RoboterModelladenButton_2Pushed, true);
            app.RoboterModelladenButton_2.Text = 'Roboter Model laden';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Panda_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end