classdef Panda_App_v02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        KalibrierenTab                  matlab.ui.container.Tab
        RoboterSimulationanzeigenButton  matlab.ui.control.Button
        WinkelHandSpinner               matlab.ui.control.Spinner
        WinkelHandSpinnerLabel          matlab.ui.control.Label
        RoboterbewegenButton            matlab.ui.control.Button
        SicherheitsabstandSpinner       matlab.ui.control.Spinner
        SicherheitsabstandSpinnerLabel  matlab.ui.control.Label
        SicherheitsabstandzumMesspunktmmLabel  matlab.ui.control.Label
        SimulierenButton                matlab.ui.control.Button
        AbstandRoboterMesspunktSpinner  matlab.ui.control.Spinner
        AbstandRoboterMesspunktSpinnerLabel  matlab.ui.control.Label
        AbstandLautsprecherzurMitteMesseinrichtungmmLabel  matlab.ui.control.Label
        AusrichtungStartpositionRoboter0GradLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        KalibrierenZSpinner             matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        KalibrierdatenspeichernButton   matlab.ui.control.Button
        KalibrierdatenladenButton       matlab.ui.control.Button
        KalibrierenYSpinner             matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        KalibrierenXSpinner             matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        AbstandTCPvomRoboterflanschmmLabel  matlab.ui.control.Label
        AbstandTCPSpinner               matlab.ui.control.Spinner
        AbstandTCPSpinnerLabel          matlab.ui.control.Label
        VektorModusTab                  matlab.ui.container.Tab
        IntervalldauerSpinner           matlab.ui.control.Spinner
        IntervalldauerSpinnerLabel      matlab.ui.control.Label
        SteuerungsmodusKnob             matlab.ui.control.DiscreteKnob
        SteuerungsmodusKnobLabel        matlab.ui.control.Label
        FormatbeiEingabexyzxyzxyzLabel  matlab.ui.control.Label
        WegpunkteausDateiladenButton    matlab.ui.control.Button
        WegpunktealsDateispeichernButton  matlab.ui.control.Button
        WegpunkteTextArea               matlab.ui.control.TextArea
        WegpunkteTextAreaLabel          matlab.ui.control.Label
        BeispieleTab                    matlab.ui.container.Tab
        RoboterModelladenButton_2       matlab.ui.control.Button
        SimulationstartenButton         matlab.ui.control.Button
        RoboterModelladenButton         matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        RunScriptButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        robot = loadrobot("frankaEmikaPanda",DataFormat="column");

    end
    
    methods (Access = public)
        
        function results = move_robot(robot, fixed_point, radius)
            % Calculate the desired position of the TCP.
    tcp_position = fixed_point + radius * [cos(angle), sin(angle), 0];

    % Calculate the joint angles for the desired position.
    q = inverse_kinematics(robot, tcp_position);

    % Move the robot to the desired position.
    moveto(robot, q);
            
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scriptPathSetupWorkspace = fullfile("setup_workspace.m");
            run(scriptPathSetupWorkspace);
        end

        % Button pushed function: RoboterModelladenButton
        function RoboterModelladenButtonPushed(app, event)
           %robot = loadrobot("frankaEmikaPanda",DataFormat="column");
           set(gcf, "Visible","on")
           show(app.robot);
        end

        % Button pushed function: SimulationstartenButton
        function SimulationstartenButtonPushed(app, event)
            %% Defining Time vector for the trajectories
            tpts = 0:4;
            sampleRate = 20;
            tvec = tpts(1):1/sampleRate:tpts(end);
            numSamples = length(tvec);
                        
            %% Task-Space Trajectories mit Inverse Kinematics
            frankaSpaceWaypoints = [0.5 0.25 0.25; 0.5 0 0.35; 0.5 -0.25 0.25; 0.5 0.25 0.25]';
            frankaTimepoints = linspace(tvec(1),tvec(end),4);
            [pos,vel] = minjerkpolytraj(frankaSpaceWaypoints,frankaTimepoints,numSamples);
            
            rng(0) % Seed the RNG so the inverse kinematics solution is consistent
            ik = inverseKinematics(RigidBodyTree=app.robot);
            ik.SolverParameters.AllowRandomRestart = false;
            q = zeros(9,numSamples);
            weights = [0.2 0.2 0.2 1 1 1]; % Prioritize position over orientation
            initialGuess = [0, 0, 0, -pi/2, 0, 0, 0, 0.01, 0.01]'; % Choose an initial guess within the robot joint limits
            for i = 1:size(pos,2)
                targetPose = trvec2tform(pos(:,i)')*eul2tform([0, 0, pi]);
                q(:,i) = ik("panda_hand",targetPose,weights,initialGuess);
                initialGuess = q(:,i); % Use the last result as the next initial guess
            end
            
            rc = rateControl(sampleRate);
            for i = 1:numSamples
                show(app.robot, q(:,i),FastUpdate=true,PreservePlot=false);
                waitfor(rc);
            end
        end

        % Callback function
        function TrajectoryButtonPushed(app, event)
            function move_robot(robot, fixed_point, radius, num_samples)

                % Define the start and end points of the trajectory.
                tcp_start_position = fixed_point - radius * [1, 0, 0];
                tcp_end_position = fixed_point + radius * [1, 0, 0];

                % Calculate the trajectory.
                tcp_trajectory = semicircle_trajectory(tcp_start_position, tcp_end_position, num_samples);

                % Move the robot along the trajectory.
                movealongtrajectory(robot, tcp_trajectory);
            end

            % Define the fixed point.
            fixed_point = [0, 0, 0];

            % Define the radius of the hemisphere.
            radius = 1;

            % Define the number of samples for the trajectory.
            num_samples = 100;

            % Move the robot along the trajectory.
            move_robot(app.robot, fixed_point, radius, num_samples);
        end

        % Button pushed function: RunScriptButton
        function RunScriptButtonPushed(app, event)
            % Get the value of the EditField
            valueEditField = app.EditField.Value

            scriptPath = fullfile("TestRunScriptFromApp.m");
            run(scriptPath);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.AbstandTCPvomRoboterflanschmmLabel.Text = "value";
        end

        % Button pushed function: KalibrierdatenladenButton
        function KalibrierdatenladenButtonPushed(app, event)
            %Initialisiert und startet Script "CalibrationData" um an die
            %Kalibrierdaten zu kommen. 
            scriptPathCalibrationData = fullfile("CalibrationData.m");
            run(scriptPathCalibrationData);

            % Globale Variablen für Setup in Zahlenfelder einfügen:
            set(app.KalibrierenXSpinner, "Value", double(kalibrieren_x));
            set(app.KalibrierenYSpinner, "Value", double(kalibrieren_y));
            set(app.KalibrierenZSpinner, "Value", double(kalibrieren_z));

            set(app.AbstandTCPSpinner, "Value", double(kalibrieren_abstand_TCP));
            set(app.AbstandRoboterMesspunktSpinner, "Value", double(kalibrieren_abstand_messpunkt));
            set(app.SicherheitsabstandSpinner, "Value", double(kalibrieren_abstand_sicherheit));
            set(app.WinkelHandSpinner, "Value", double(kalibrieren_winkel_TCP));

        end

        % Button pushed function: KalibrierdatenspeichernButton
        function KalibrierdatenspeichernButtonPushed(app, event)
            %Initialisiert und startet Script "CalibrationData" um an die
            %Kalibrierdaten zu kommen.  ||  ACHTUNG!!! Gesamte Funktion ist
            %exakt auch in CalibrationData.m im Skript. Wenn etwas geändert
            %werden soll, dann also immer bei beiden Dateien.
            scriptPathCalibrationData = fullfile("CalibrationData.m");
            run(scriptPathCalibrationData);   
            
            % Werte der Eingabefelder lesen
                x = get(app.KalibrierenXSpinner, 'Value');
                y = get(app.KalibrierenYSpinner, 'Value');
                z = get(app.KalibrierenZSpinner, 'Value');
             
                TCP = get(app.AbstandTCPSpinner, 'Value');
                messpunkt = get(app.AbstandRoboterMesspunktSpinner, 'Value');
                sicherheit = get(app.SicherheitsabstandSpinner, 'Value');
                winkel = get(app.WinkelHandSpinner, 'Value');

        % Werte der Eingabefelder in die globalen Variablen schreiben
                kalibrieren_x = x;
                kalibrieren_y = y;
                kalibrieren_z = z;
               
                kalibrieren_abstand_TCP = TCP;
                kalibrieren_abstand_messpunkt = messpunkt;
                kalibrieren_abstand_sicherheit = sicherheit;
                kalibrieren_winkel_TCP = winkel;


        % Globale Variablen in das Skript speichern
                save(fullfile('CalibrationData.mat'), 'kalibrieren_x', 'kalibrieren_y', 'kalibrieren_z', 'kalibrieren_abstand_TCP', 'kalibrieren_abstand_messpunkt', 'kalibrieren_abstand_sicherheit','kalibrieren_winkel_TCP');
        end

        % Button pushed function: RoboterModelladenButton_2
        function RoboterModelladenButton_2Pushed(app, event)
           set(gcf, "Visible","on")
           show(app.robot);
        end

        % Button pushed function: RoboterSimulationanzeigenButton
        function RoboterSimulationanzeigenButtonPushed(app, event)
            % Make the figure visible
            set(gcf, "Visible", "on");


            % Show the reference point
            show(app.robot);
            hold on;
            fixedPoint = [1, 0, 0.5];
            % Plot the fixed point as a red ball
            plot3(fixedPoint(1), fixedPoint(2), fixedPoint(3), 'ro', 'MarkerSize', 5, 'LineWidth', 5);
            hold off;
        end

        % Button pushed function: WegpunktealsDateispeichernButton
        function WegpunktealsDateispeichernButtonPushed(app, event)
    % Get the content of the "Wegpunkte" TextArea
    waypoints = app.WegpunkteTextArea.Value;

    % Open an explorer window and allow the user to name the file
    [filename, pathname] = uiputfile('*.mat', 'Save Waypoints');
    
    % If the user selected a file, save the waypoints to the file
    if filename ~= 0
        save(fullfile(pathname, filename), 'waypoints');
    end

        end

        % Button pushed function: WegpunkteausDateiladenButton
        function WegpunkteausDateiladenButtonPushed(app, event)
            % Open an explorer window and allow the user to select a file
            [filename, pathname] = uigetfile('*.mat', 'Load Waypoints');

            % If the user selected a file, load the waypoints from the file
            if filename ~= 0
                load(fullfile(pathname, filename));
                app.WegpunkteTextArea.Value = waypoints;
    end
        end

        % Value changed function: SteuerungsmodusKnob
        function SteuerungsmodusKnobValueChanged(app, event)
            state = app.SteuerungsmodusKnob.Value;
            % If the state is "Zeitgesteuert", enable the interval duration spinner
            if state == "Zeitgesteuert"
                app.IntervalldauerSpinner.Enable = true;
            else
                app.IntervalldauerSpinner.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KalibrierenTab = uitab(app.TabGroup);
            app.KalibrierenTab.Title = 'Kalibrieren';
            app.AbstandTCPSpinnerLabel = uilabel(app.KalibrierenTab);
            app.AbstandTCPSpinnerLabel.Text = 'Abstand TCP';
            app.AbstandTCPSpinner = uispinner(app.KalibrierenTab);
            app.AbstandTCPvomRoboterflanschmmLabel = uilabel(app.KalibrierenTab);
            app.AbstandTCPvomRoboterflanschmmLabel.Text = 'Abstand TCP vom Roboterflansch [mm]';
            app.XSpinnerLabel = uilabel(app.KalibrierenTab);
            app.XSpinnerLabel.Text = 'X';
            app.KalibrierenXSpinner = uispinner(app.KalibrierenTab);
            app.YSpinnerLabel = uilabel(app.KalibrierenTab);
            app.YSpinnerLabel.Text = 'Y';
            app.KalibrierenYSpinner = uispinner(app.KalibrierenTab);
            app.KalibrierdatenladenButton = uibutton(app.KalibrierenTab, 'push');
            app.KalibrierdatenladenButton.ButtonPushedFcn = createCallbackFcn(app, @KalibrierdatenladenButtonPushed, true);
            app.KalibrierdatenladenButton.Text = 'Kalibrierdaten laden';
            app.KalibrierdatenspeichernButton = uibutton(app.KalibrierenTab, 'push');
            app.KalibrierdatenspeichernButton.ButtonPushedFcn = createCallbackFcn(app, @KalibrierdatenspeichernButtonPushed, true);
            app.KalibrierdatenspeichernButton.Text = 'Kalibrierdaten speichern';
            app.ZSpinnerLabel = uilabel(app.KalibrierenTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.KalibrierenZSpinner = uispinner(app.KalibrierenTab);
            app.Image = uiimage(app.KalibrierenTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Panda_Koordinatensystem.png');
            app.AusrichtungStartpositionRoboter0GradLabel = uilabel(app.KalibrierenTab);
            app.AusrichtungStartpositionRoboter0GradLabel.Text = 'Ausrichtung Startposition Roboter (0 Grad)';
            app.AbstandLautsprecherzurMitteMesseinrichtungmmLabel = uilabel(app.KalibrierenTab);
            app.AbstandLautsprecherzurMitteMesseinrichtungmmLabel.Text = 'Abstand Lautsprecher zur Mitte Messeinrichtung [mm]';
            app.AbstandRoboterMesspunktSpinnerLabel = uilabel(app.KalibrierenTab);
            app.AbstandRoboterMesspunktSpinnerLabel.Text = 'Abstand Roboter Messpunkt';
            app.AbstandRoboterMesspunktSpinner = uispinner(app.KalibrierenTab);
            app.SimulierenButton = uibutton(app.KalibrierenTab, 'push');
            app.SimulierenButton.Text = 'Simulieren';
            app.SicherheitsabstandzumMesspunktmmLabel = uilabel(app.KalibrierenTab);
            app.SicherheitsabstandzumMesspunktmmLabel.Text = 'Sicherheitsabstand zum Messpunkt [mm]';
            app.SicherheitsabstandSpinnerLabel = uilabel(app.KalibrierenTab);
            app.SicherheitsabstandSpinnerLabel.Text = 'Sicherheitsabstand';
            app.SicherheitsabstandSpinner = uispinner(app.KalibrierenTab);
            app.RoboterbewegenButton = uibutton(app.KalibrierenTab, 'push');
            app.RoboterbewegenButton.Text = 'Roboter bewegen';
            app.WinkelHandSpinnerLabel = uilabel(app.KalibrierenTab);
            app.WinkelHandSpinnerLabel.Text = 'Winkel Hand';
            app.WinkelHandSpinner = uispinner(app.KalibrierenTab);
            app.RoboterSimulationanzeigenButton = uibutton(app.KalibrierenTab, 'push');
            app.RoboterSimulationanzeigenButton.ButtonPushedFcn = createCallbackFcn(app, @RoboterSimulationanzeigenButtonPushed, true);
            app.RoboterSimulationanzeigenButton.Text = 'Roboter Simulation anzeigen';
            app.VektorModusTab = uitab(app.TabGroup);
            app.VektorModusTab.Title = 'Vektor-Modus';
            app.WegpunkteTextAreaLabel = uilabel(app.VektorModusTab);
            app.WegpunkteTextAreaLabel.Text = 'Wegpunkte';
            app.WegpunkteTextArea = uitextarea(app.VektorModusTab);
            app.WegpunkteTextArea.Tooltip = {'Eingabe der Wegpunkte als Vektoren [x, y, z]'};
            app.WegpunktealsDateispeichernButton = uibutton(app.VektorModusTab, 'push');
            app.WegpunktealsDateispeichernButton.ButtonPushedFcn = createCallbackFcn(app, @WegpunktealsDateispeichernButtonPushed, true);
            app.WegpunktealsDateispeichernButton.Text = 'Wegpunkte als Datei speichern';
            app.WegpunkteausDateiladenButton = uibutton(app.VektorModusTab, 'push');
            app.WegpunkteausDateiladenButton.ButtonPushedFcn = createCallbackFcn(app, @WegpunkteausDateiladenButtonPushed, true);
            app.WegpunkteausDateiladenButton.Text = 'Wegpunkte aus Datei laden';
            app.FormatbeiEingabexyzxyzxyzLabel = uilabel(app.VektorModusTab);
            app.FormatbeiEingabexyzxyzxyzLabel.Text = {'Format bei Eingabe: '; ''; 'x, y, z'; 'x, y, z'; 'x, y, z'};
            app.SteuerungsmodusKnobLabel = uilabel(app.VektorModusTab);
            app.SteuerungsmodusKnobLabel.Text = 'Steuerungsmodus';
            app.SteuerungsmodusKnob = uiknob(app.VektorModusTab, 'discrete');
            app.SteuerungsmodusKnob.Items = {'Kontinuierlich', 'Zeitgesteuert', 'Bestätigung'};
            app.SteuerungsmodusKnob.ValueChangedFcn = createCallbackFcn(app, @SteuerungsmodusKnobValueChanged, true);
            app.SteuerungsmodusKnob.Value = 'Kontinuierlich';
            app.IntervalldauerSpinnerLabel = uilabel(app.VektorModusTab);
            app.IntervalldauerSpinnerLabel.Enable = 'off';
            app.IntervalldauerSpinnerLabel.Text = 'Intervalldauer';
            app.IntervalldauerSpinner = uispinner(app.VektorModusTab);
            app.IntervalldauerSpinner.Step = 0.1;
            app.IntervalldauerSpinner.Limits = [0 Inf];
            app.IntervalldauerSpinner.ValueDisplayFormat = '%.2f';
            app.IntervalldauerSpinner.Enable = 'off';
            app.IntervalldauerSpinner.Tooltip = {'Intervalldauer zwischen den einzelnen Schritten in [s]'};
            app.BeispieleTab = uitab(app.TabGroup);
            app.BeispieleTab.Title = 'Beispiele';
            app.RunScriptButton = uibutton(app.BeispieleTab, 'push');
            app.RunScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptButtonPushed, true);
            app.RunScriptButton.Text = 'RunScript';
            app.EditFieldLabel = uilabel(app.BeispieleTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.BeispieleTab, 'numeric');
            app.RoboterModelladenButton = uibutton(app.BeispieleTab, 'push');
            app.RoboterModelladenButton.ButtonPushedFcn = createCallbackFcn(app, @RoboterModelladenButtonPushed, true);
            app.RoboterModelladenButton.Text = 'Roboter Model laden';
            app.SimulationstartenButton = uibutton(app.BeispieleTab, 'push');
            app.SimulationstartenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationstartenButtonPushed, true);
            app.SimulationstartenButton.Text = 'Simulation starten';
            app.RoboterModelladenButton_2 = uibutton(app.BeispieleTab, 'push');
            app.RoboterModelladenButton_2.ButtonPushedFcn = createCallbackFcn(app, @RoboterModelladenButton_2Pushed, true);
            app.RoboterModelladenButton_2.Text = 'Roboter Model laden';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Panda_App_v02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end